classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChooseCSVFileButton      matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        SliderLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        PlaybackButton           matlab.ui.control.Button
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        YourspeedEditFieldLabel  matlab.ui.control.Label
        YourspeedEditField       matlab.ui.control.NumericEditField
        YourlocationGPSLabel     matlab.ui.control.Label
        EditField2               matlab.ui.control.NumericEditField
        HellowelcometodiscretizersappLabel  matlab.ui.control.Label
        ChooselocationLabel      matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = linspace(0,1653,10)
            app.UIAxes.YTick = linspace(0,1559,10)
            app.UIAxes.XLim = [0 1653];
            app.UIAxes.YLim = [0 1559];
            app.UIAxes.YDir = 'reverse';
            
            imshow('CampusMap.png', 'Parent',app.UIAxes);
            disableDefaultInteractivity(app.UIAxes)
        end

        % Button pushed function: ChooseCSVFileButton
        function ChooseCSVFileButtonPushed(app, event)
           map = mapping;
           
           disableDefaultInteractivity(app.UIAxes)
           
           app.UIAxes.XTick = linspace(0,1653,10)
           app.UIAxes.YTick = linspace(0,1559,10)
           app.UIAxes.XLim = [0 1653];
           app.UIAxes.YLim = [0 1559];
           app.UIAxes.YDir = 'reverse';
           
           re = imread('CampusMap.png');
           im = image(app.UIAxes.XLim, app.UIAxes.YLim, re, 'Parent',app.UIAxes);
 
           hold(app.UIAxes, 'on');
           plot(app.UIAxes, map.x,map.y, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseCSVFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVFileButtonPushed, true);
            app.ChooseCSVFileButton.Text = 'Choose CSV File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mapping')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.PlaybackButton = uibutton(app.UIFigure, 'push');
            app.PlaybackButton.Text = 'Playback';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%11.6g';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YourspeedEditFieldLabel = uilabel(app.UIFigure);
            app.YourspeedEditFieldLabel.Text = 'Your speed:';
            app.YourspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.YourspeedEditField.ValueDisplayFormat = '%11.6g';
            app.YourlocationGPSLabel = uilabel(app.UIFigure);
            app.YourlocationGPSLabel.Text = 'Your location(GPS):';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueDisplayFormat = '%11.6g';
            app.HellowelcometodiscretizersappLabel = uilabel(app.UIFigure);
            app.HellowelcometodiscretizersappLabel.Text = 'Hello, welcome to discretizers app';
            app.ChooselocationLabel = uilabel(app.UIFigure);
            app.ChooselocationLabel.Text = 'Choose location';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Option 4', 'Option 5', 'Option 6'};
            app.DropDown.Value = 'Option 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end